classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        wLabel                         matlab.ui.control.Label
        c_w_gesLabel                   matlab.ui.control.Label
        phi50Label                     matlab.ui.control.Label
        A_refLabel                     matlab.ui.control.Label
        FlossenanzahlEditField         matlab.ui.control.NumericEditField
        FlossenanzahlEditFieldLabel    matlab.ui.control.Label
        StreckungEditField             matlab.ui.control.NumericEditField
        StreckungEditFieldLabel        matlab.ui.control.Label
        HinterkantenpfeilungEditField  matlab.ui.control.NumericEditField
        HinterkantenpfeilungEditFieldLabel  matlab.ui.control.Label
        VorderkantenpfeilungEditField  matlab.ui.control.NumericEditField
        VorderkantenpfeilungEditFieldLabel  matlab.ui.control.Label
        FlaechenverhaeltnisEditField   matlab.ui.control.NumericEditField
        FlchenverhltnisEditFieldLabel  matlab.ui.control.Label
        RumpfdurchmessermmEditField    matlab.ui.control.NumericEditField
        RumpfdurchmessermmEditFieldLabel  matlab.ui.control.Label
        NasenformDropDown              matlab.ui.control.DropDown
        NasenformDropDownLabel         matlab.ui.control.Label
        RumpflaengeEditField           matlab.ui.control.NumericEditField
        RumpflngecmLabel               matlab.ui.control.Label
        NasenlaengeEditField           matlab.ui.control.NumericEditField
        NasenlngecmLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        d_flosse = 0.003; % 3 mm
        u_mittel = 30; % 30m/s
        mu0 = 17.17e-6; % Dynamische Viskosität auf Meereshöhe nach ISA
        Temp0 = 288.15; % Kelvin
        rho0 = 1.225; % kg/m3
        v0 = 14.607e-6; % kinematische Viskositaet auf Seehoehe
    end

    properties (Dependent)
        A_ref
        d_rumpf
        l_rumpf
        l_nase
        l_rohr

        % nur getter, keine Unit conversion etc.
        n_flossen
    end
    
    methods % nur getter und setter
        function a = get.A_ref(app) % Referenzfläche A_ref (m^2)
            a = pi * (app.d_rumpf^2 / 4); % mm in m, d -> r
            app.A_refLabel.Text = app.prep_latex_str("A_\text{ref}", a * 1e4 ,"cm^2");
        end

        function a = get.d_rumpf(app) % Rumpfdurchmesser (m)
            a = app.RumpfdurchmessermmEditField.Value / 1000; % mm -> m
        end

        function a = get.l_rumpf(app)
            a = app.RumpflaengeEditField.Value / 100; % cm -> m
        end

        function a = get.l_nase(app)
            a = app.NasenlaengeEditField.Value / 100; % cm -> m
        end

        function n = get.n_flossen(app)
            n = app.FlossenanzahlEditField.Value;
        end

        function l = get.l_rohr(app)
            l = app.l_rumpf - app.l_nase;
        end
    end

    methods (Static) % static methods
        function s = prep_latex_str(name, value, unit)
            s = compose("$%s = %9.3f\\,\\mathrm{%s}$", name, value, unit);
        end
    end

    methods (Access = private)
        function r = gesamtwiderstand(app) % (kg*m/s^2 == W/s)
            r = app.gesamtwiderstandsbeiwert() * app.rho0 * app.u_mittel^2 * 0.5 * app.A_ref;
            app.c_w_gesLabel.Text = app.prep_latex_str("c_{w_\text{ges}}", r, "N");
        end
        
        function r = gesamtwiderstandsbeiwert(app) % einheitslos
           r = app.c_w_rumpf_min() * app.A_mantel()/app.A_ref + app.n_flossen * app.c_w_flosse_min() * app.A_Flosse()/app.A_ref;
           app.wLabel.Text = app.prep_latex_str("\text{Gesamtwiderstand }W", r, "W/s");
        end
        
        function A = A_mantel(app) % Enthält die Mantelfläche auch den Boden am Ende? (das ist hier + A_ref)
            r_rumpf = app.d_rumpf/2; % convenience
            % formel:
            % https://www.wolframalpha.com/input?i2d=true&i=simplify+2*pi*Integrate%5BRSqrt%5BDivide%5Bx%2CL%5D%5DSqrt%5BDivide%5BPower%5BR%2C2%5D%2B4*L*x%2C4+L*x%5D%5D%2C%7Bx%2C0%2CL%7D%5D%5C%2844%29+L%3E0%5C%2844%29R%3E0

            if app.NasenformDropDown.Value == "Parabel" % Parabolid
                % TODO: check!
                A = (pi * r_rumpf * (sqrt(4*app.l_nase^2/r_rumpf^2 + 1)*(4 * app.l_nase^2 + r_rumpf^2) - r_rumpf^2))/6 * app.l_nase^2 / r_rumpf;
            else % Ellipsoid
                % formel für die Oberfläche eines Ellipsoiden (Wikipedia)
                sqrt_c2_minus_a2 = sqrt(app.l_nase^2 - r_rumpf^2); % c ist nasenlaenge und a ist rumpfradius
                A = pi * r_rumpf * (r_rumpf + (app.l_nase^2)/sqrt_c2_minus_a2 * asin(sqrt_c2_minus_a2/app.l_nase));
            end
            A = A + app.d_rumpf * pi * app.l_rohr + app.A_ref;
            fprintf('Nasenoberflaeche: %f m^2\n', A);
        end

        function r = c_w_flosse_min(app)

            re = app.reynolds(app.l_m());
            r = 2 * app.c_f_tu(re) * (1 + app.k_flosse() * cos(app.phi50())^2);
        end
        
        function r = k_flosse(app) % einheitlos
            delta = app.d_flosse/app.l_m();
            r = 2.7 * delta + 100 * delta^4;
        end
        
        function r = l_m(app) % Mittlere Flossentiefe: aus folien (m)
            r = app.A_Flosse()/app.s();
        end

        function r = s(app) % (Flossen-)Spannweite (m)
            r = sqrt(app.StreckungEditField.Value * app.A_Flosse());
        end
        
        function r = A_Flosse(app) % (m^2)
            r = app.A_Lat() * app.FlaechenverhaeltnisEditField.Value; 
        end

        function r = A_Lat(app) % (m^2)
            r = app.d_rumpf * (app.l_rumpf-app.l_nase) + A_Nose(app);
            
        end
        
        function r = A_Nose(app) % complete
            % 0: parabel
            % 1: ellipse
            if app.NasenformDropDown.Value == "Parabel" 
                r = (app.d_rumpf * app.l_nase)/3; % A(l,r)=(2/3)*l*r
            else
                r = 0.25 * app.d_rumpf * app.l_nase * pi; % 0.5 * r * l
            end  
        end
        
        function r = c_f_tu(~,re) % c_f (Turbolent), einheitslos
            r = 0.455/(log10(re)^2.58);
        end
        
        function r = phi50(app) % mittelwert aus vorder- und hinterkantenpfeilung, ig
            r = (app.VorderkantenpfeilungEditField.Value + app.HinterkantenpfeilungEditField.Value)/2;
            app.phi50Label.Text = app.prep_latex_str("\varphi_{50}", r, "\!°");
        end
        
        function r = reynolds(app, laenge) % einheitslos
            r = (app.u_mittel * laenge) / app.v0;
        end

        function r = k_rumpf(app) % einheitslos
            lambda_rumpf_eff = app.l_rumpf/app.d_rumpf; % Schlankheitsmaß lambda
            r = (2.2/(lambda_rumpf_eff^1.5)) + (3.8/(lambda_rumpf_eff^3));
        end

        function r = c_w_rumpf_min(app) % einheitslos
            r = app.c_f_tu(app.reynolds(app.l_rumpf)) * (1+app.k_rumpf());
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function showApp(app)
            app.recomputeValues();
        end

        % Value changed function: FlaechenverhaeltnisEditField, 
        % ...and 8 other components
        function recomputeValues(app, event)
            app.gesamtwiderstandsbeiwert();
            app.gesamtwiderstand();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NasenlngecmLabel = uilabel(app.GridLayout);
            app.NasenlngecmLabel.Text = 'Nasenlänge (cm)';
            app.NasenlaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.NasenlaengeEditField.Limits = [0 Inf];
            app.NasenlaengeEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.NasenlaengeEditField.Value = 5;
            app.RumpflngecmLabel = uilabel(app.GridLayout);
            app.RumpflngecmLabel.Text = 'Rumpflänge (cm)';
            app.RumpflaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.RumpflaengeEditField.Limits = [0 Inf];
            app.RumpflaengeEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.RumpflaengeEditField.Value = 40;
            app.NasenformDropDownLabel = uilabel(app.GridLayout);
            app.NasenformDropDownLabel.Text = 'Nasenform';
            app.NasenformDropDown = uidropdown(app.GridLayout);
            app.NasenformDropDown.Items = {'Parabel', 'Ellipse'};
            app.NasenformDropDown.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.NasenformDropDown.Value = 'Parabel';
            app.RumpfdurchmessermmEditFieldLabel = uilabel(app.GridLayout);
            app.RumpfdurchmessermmEditFieldLabel.Text = 'Rumpfdurchmesser (mm)';
            app.RumpfdurchmessermmEditField = uieditfield(app.GridLayout, 'numeric');
            app.RumpfdurchmessermmEditField.Limits = [35 40];
            app.RumpfdurchmessermmEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.RumpfdurchmessermmEditField.Value = 35;
            app.FlchenverhltnisEditFieldLabel = uilabel(app.GridLayout);
            app.FlchenverhltnisEditFieldLabel.Text = 'Flächenverhältnis';
            app.FlaechenverhaeltnisEditField = uieditfield(app.GridLayout, 'numeric');
            app.FlaechenverhaeltnisEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.FlaechenverhaeltnisEditField.Value = 0.2;
            app.VorderkantenpfeilungEditFieldLabel = uilabel(app.GridLayout);
            app.VorderkantenpfeilungEditFieldLabel.Text = 'Vorderkantenpfeilung';
            app.VorderkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.VorderkantenpfeilungEditField.Limits = [0 90];
            app.VorderkantenpfeilungEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.HinterkantenpfeilungEditFieldLabel = uilabel(app.GridLayout);
            app.HinterkantenpfeilungEditFieldLabel.Text = 'Hinterkantenpfeilung';
            app.HinterkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.HinterkantenpfeilungEditField.Limits = [0 90];
            app.HinterkantenpfeilungEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.HinterkantenpfeilungEditField.Value = 20;
            app.StreckungEditFieldLabel = uilabel(app.GridLayout);
            app.StreckungEditFieldLabel.Text = 'Streckung';
            app.StreckungEditField = uieditfield(app.GridLayout, 'numeric');
            app.StreckungEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.StreckungEditField.Value = 2;
            app.FlossenanzahlEditFieldLabel = uilabel(app.GridLayout);
            app.FlossenanzahlEditFieldLabel.Text = 'Flossenanzahl';
            app.FlossenanzahlEditField = uieditfield(app.GridLayout, 'numeric');
            app.FlossenanzahlEditField.Limits = [2 8];
            app.FlossenanzahlEditField.RoundFractionalValues = 'on';
            app.FlossenanzahlEditField.ValueChangedFcn = createCallbackFcn(app, @recomputeValues, true);
            app.FlossenanzahlEditField.Value = 3;
            app.A_refLabel = uilabel(app.GridLayout);
            app.A_refLabel.Interpreter = 'latex';
            app.A_refLabel.Text = 'A_{ref}';
            app.phi50Label = uilabel(app.GridLayout);
            app.phi50Label.Interpreter = 'latex';
            app.phi50Label.Text = '$\varphi_{50}=-1\,\text{m}$';
            app.c_w_gesLabel = uilabel(app.GridLayout);
            app.c_w_gesLabel.Interpreter = 'latex';
            app.c_w_gesLabel.Text = '$c_{w_\mathrm{ges}}=-1$';
            app.wLabel = uilabel(app.GridLayout);
            app.wLabel.Interpreter = 'latex';
            app.wLabel.Text = 'Gesamtwiderstand $w$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @showApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end